classdef MicroPlateBrowser_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MicroplateanalysisUIFigure  matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        PlateBrowserTab             matlab.ui.container.Tab
        Microplateplot              matlab.ui.control.UIAxes
        CurveAxes                   matlab.ui.control.UIAxes
        NextFileButton              matlab.ui.control.Button
        PreviousFileButton          matlab.ui.control.Button
        ClearselectionButton        matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        FileNameField               matlab.ui.control.EditField
        CurrentFileLabel            matlab.ui.control.Label
        SelectFilesButton           matlab.ui.control.Button
        SummaryTableTab             matlab.ui.container.Tab
        AnalyzeSelectedFilesButton  matlab.ui.control.Button
        TabGroup2                   matlab.ui.container.TabGroup
        TopResultsTab               matlab.ui.container.Tab
        TopResultsTable             matlab.ui.control.Table
        TopPercentiletoDisplayEditFieldLabel  matlab.ui.control.Label
        TopPercentiletoDisplayEditField  matlab.ui.control.NumericEditField
        CurveAxes_2                 matlab.ui.control.UIAxes
        AllResultsTab               matlab.ui.container.Tab
        AllResultsTable             matlab.ui.control.Table
        SaveResultsButton           matlab.ui.control.Button
    end

% Copyright 2017 The MathWorks, Inc.
% Developed by Jeff Gruneich, Ph.D., Heather Gorr, Ph.D., Jeremy Huard. M.S.
    
    properties (Access = private)
        FileNames
        CurrentFileNumber = 1
        analyzeallfiles_state = 1
        finaltable
        plate_result
        topleads
        ds     % datastore variable
    end
    
    
    methods (Access = private)
        
        function analyzeonefile(app, filenumber)
            
                       
            % LOAD DATA: Read data from the datastore        
            % [data,info] = read(app.ds);
            filename = app.ds.Files{filenumber};
            
            data = readtable(filename);
            data.Properties.VariableNames= app.ds.VariableNames;
            
            %% APPLY POSITIVE AND NEGATIVE CONTROLS
            data = my_plate_controls(data);
            
            % Now that we have applied the controls, we can generate a microplate plot from the raw data
            % Note - this function is custom-developed for this app
            UImicroplateplot(app.Microplateplot, data.Variables)
            
            %% ASSOCIATE FILE NAMES AND COMPOUND NAMES
            plate = my_file_metadata(data, filename);
            
            %% ITERATE THROUGH EACH ROW OF THE PLATE FOR CURVE FIT AND QC DATA
            % let's say 1 <---> 1 uM so we're ranging from 1 nM to 1 M, for example
            X = log10([0.001, 0.01, 0.1, 1, 10, 100, 1000, 10000, 100000, 1000000]);
            
            % Generate this specifically for the app
            Xplot = linspace(-3,6,200);
            green = [0.29,0.80,0.52];
            red   = [0.8,0.1,0.1];
            
            % Select variables of interest
            idx = contains(plate.Properties.VariableNames,'Conc');
            
            for ii = 1:height(plate)
                % Assign to Y the preprocessed data (change X inside the function if needed)
                Y = plate{ii,idx};
                
                % CURVE FIT: pass a preprocessed row of data (Y) to our custom 4
                % parameter fit and get out a curve and associated parameters
                % note: for faster performance turn of plotting in this function and just plot from app designer
                [fitresult, gof] = my_four_parameter_fit(X, Y);
                
                
                % build up the platefit table table of eight rows (one plate)
                fit_parameters_row(ii,:) = my_fit_parameters( fitresult, gof );
                
                % depending on which state (which tab) the user interface is in,
                if app.analyzeallfiles_state==1
                    % Plot the curve in the app window
                    plot(app.CurveAxes, X, Y, 'k+')
                    Ycurve = fitresult(Xplot);
                    ch = plot(app.CurveAxes, Xplot, Ycurve,'Color',green);
                 else 
                    plot(app.CurveAxes_2, X, Y, 'k+')
                    Ycurve = fitresult(Xplot);
                    ch = plot(app.CurveAxes_2, Xplot, Ycurve,'Color',green);
                end    
                
                % QUALITY CONTROL: pass EC50 and Hills_Slope into the this custom QC
                % function inspired by the literature and get back a QC table row
                qc = my_qc(fit_parameters_row.EC50(ii), fit_parameters_row.Hills_Slope(ii));
                % build up the Quality Control (qc) table
                plate_qc(ii,:) = qc;
                
                % depending on whether the results are reliable,
                if ~qc.Reliable
                    ch.Color = red;
                end
            end
            
            %% MERGE RESULTS FROM EACH SECTION INTO A SINGLE TABLE which can be used globally in the app
            app.plate_result = [plate, fit_parameters_row, plate_qc];
            
            if app.analyzeallfiles_state==1
                % PUT THE TABLE IN THE UI TABLE
                app.plate_result.FileName = cellstr(app.plate_result.FileName);
                app.UITable.Data = table2cell(app.plate_result);
                
                % Color row of non-reliable data in red
                app.UITable.BackgroundColor = repmat([1 1 1;0.94 0.94 0.94],...
                    ceil(height(app.plate_result)/2),1);
                app.UITable.BackgroundColor(~app.plate_result.Reliable,:) = ...
                    repmat([1 0.7 0.7], sum(~app.plate_result.Reliable),1);
            end
        end        
        
        function deleteResults(app)
               % app.TopResultsTable.Data = [];
               % app.AllResultsTable.Data = [];
               % delete(app.CurveAxes_2.Children);
                app.UITable.Data = [];
                delete(app.CurveAxes.Children);
                delete(app.Microplateplot.Children);
                colorbar(app.Microplateplot,'off'); 
        end
        
        function curvesforTopResultsTab(app)
            % Iterate through each row of the plate to get curve plots and the parameters for the curve fit and qc tables
            % let's say 1 <---> 1 uM so we're ranging from 1 nM to 1 M, for example
            X     = log10([0.001, 0.01, 0.1, 1, 10, 100, 1000, 10000, 100000, 1000000]);
            Xplot = linspace(-4,7,100);
            green = [0.29,0.80,0.52];
            red   = [0.8,0.1,0.1];
            
            delete(app.CurveAxes_2.Children);
            
            for jj = 1: height(app.topleads)
                Y = cell2mat(app.AllResultsTable.Data(jj,4:13));     % how to access part of a cell array and convert to double
                % Plot the curve in the app window
                plot(app.CurveAxes_2, X, Y, 'k+')
                fitresult = my_four_parameter_fit(X, Y);
                Ycurve = fitresult(Xplot);
                ch = plot(app.CurveAxes_2, Xplot, Ycurve,'Color',green);
                
                
                % depending on whether the results are reliable,
                if ~app.topleads.Reliable(jj) 
                    ch.Color = red;
                end
                

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Put app in center of screen
            screensize = get(groot,'ScreenSize');
            center = [screensize(1) + screensize(3)/2, screensize(2) + screensize(4)/2];
            posx = center(1) - app.MicroplateanalysisUIFigure.Position(3)/2;
            posy = center(2) - app.MicroplateanalysisUIFigure.Position(4)/2;
            app.MicroplateanalysisUIFigure.Position(1:2) = [posx, posy];
            
            % Hide plots (keep table visible because of rescaling issue)
            app.Microplateplot.Visible = 'off';
            app.CurveAxes.Visible = 'off';
        end

        % Button pushed function: AnalyzeSelectedFilesButton
        function AnalyzeSelectedFilesButtonPushed(app, event)
            app.analyzeallfiles_state = 2;
            delete(app.CurveAxes_2.Children);
            app.finaltable = table;
            
            reset(app.ds)     % reset datastore
            
            hold (app.CurveAxes_2, 'on')
            
            for ii = 1:numel(app.FileNames)
                analyzeonefile(app, ii);
                app.finaltable = vertcat(app.finaltable, app.plate_result);                
            end
            app.finaltable = sortrows(app.finaltable,'EC50');
            app.finaltable = sortrows(app.finaltable, 'Reliable','descend');
            
            % PUT THE TABLE IN THE SUMMARY TABLES
            % results in the All Results Tab
            app.finaltable.FileName = cellstr(app.finaltable.FileName);
            app.AllResultsTable.Data = table2cell(app.finaltable);
            
            
            % Color row of non-reliable data in red
            app.AllResultsTable.BackgroundColor = repmat([1 1 1;0.94 0.94 0.94],...
                ceil(height(app.finaltable)/2),1);
            app.AllResultsTable.BackgroundColor(~app.finaltable.Reliable,:) = ...
                repmat([1 0.7 0.7], sum(~app.finaltable.Reliable),1);
            
            % results in the Top Results Tab
            cutoff = app.TopPercentiletoDisplayEditField.Value;
            app.topleads = app.finaltable(1:((cutoff/100)*height(app.finaltable)) ,...
                {'FileName', 'CompoundNumber', 'EC50', 'Reliable'});
            app.TopResultsTable.Data = table2cell(app.topleads);
            
            % Color row of non-reliable data in red
            app.TopResultsTable.BackgroundColor = repmat([1 1 1;0.94 0.94 0.94],...
                ceil(height(app.topleads)/2),1);
            app.TopResultsTable.BackgroundColor(~app.topleads.Reliable,:) = ...
                repmat([1 0.7 0.7], sum(~app.topleads.Reliable),1);
                
            % curvesforTopResultsTab(app)  


        end

        % Button pushed function: ClearselectionButton
        function ClearselectionButtonPushed(app, event)
            % Delete rectangle in Microplate plot
            rectObj = findobj(app.Microplateplot.Children,'Type','Rectangle');
            delete(rectObj);
            
            % Delete Curve Selection
            curves = findobj(app.CurveAxes,'LineStyle','-');
            set(curves, 'Selected','off')
            
            % Deactivate button
            app.ClearselectionButton.Enable = 'off';
        end

        % Button pushed function: NextFileButton
        function NextFileButtonPushed(app, event)
            app.analyzeallfiles_state = 1;
            % Delete plots and table
            deleteResults(app);
            
            if app.CurrentFileNumber < numel(app.FileNames)
                app.CurrentFileNumber = app.CurrentFileNumber + 1;
                app.FileNameField.Value  = app.FileNames{app.CurrentFileNumber};
                analyzeonefile(app, app.CurrentFileNumber);
            end
        end

        % Button pushed function: PreviousFileButton
        function PreviousFileButtonPushed(app, event)
            app.analyzeallfiles_state = 1;
            
            % Delete plots and table
            deleteResults(app);
            
            if app.CurrentFileNumber > 1
                app.CurrentFileNumber = app.CurrentFileNumber - 1;
                app.FileNameField.Value  = app.FileNames{app.CurrentFileNumber};
                analyzeonefile(app, app.CurrentFileNumber);
            end
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            [ ~, ~ ] = my_export_microplate_results( app.finaltable )
            winopen('Final_Microplate_Results.xlsx')
                        
        end

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            app.analyzeallfiles_state = 1;
            
            deleteResults(app)
            
            % Custom function to select files to process
            [app.FileNames,app.ds] = my_select_microtiter_files;
            
            % Display the name of the file selected in the FileNameField in the app
            if ~isempty(app.FileNames)
                app.FileNameField.Value = app.FileNames{1};
                
                app.Microplateplot.Visible = 'on';
                app.CurveAxes.Visible = 'on';
                
                analyzeonefile(app, app.CurrentFileNumber);
            end
        end

        % Value changed function: TopPercentiletoDisplayEditField
        function TopPercentiletoDisplayEditFieldValueChanged(app, event)
            app.analyzeallfiles_state = 2;
            
            app.AllResultsTable.BackgroundColor = repmat([1 1 1;0.94 0.94 0.94],...
                ceil(height(app.finaltable)/2),1);
            app.AllResultsTable.BackgroundColor(~app.finaltable.Reliable,:) = ...
                repmat([1 0.7 0.7], sum(~app.finaltable.Reliable),1);
            
            % results in the Top Results Tab
            cutoff = app.TopPercentiletoDisplayEditField.Value;
            app.topleads = app.finaltable(1:((cutoff/100)*height(app.finaltable)) ,...
                {'FileName', 'CompoundNumber', 'EC50', 'Reliable'});
            app.TopResultsTable.Data = table2cell(app.topleads);
            
            % Color row of non-reliable data in red
            app.TopResultsTable.BackgroundColor = repmat([1 1 1;0.94 0.94 0.94],...
                ceil(height(app.topleads)/2),1);
            app.TopResultsTable.BackgroundColor(~app.topleads.Reliable,:) = ...
                repmat([1 0.7 0.7], sum(~app.topleads.Reliable),1);
     
            curvesforTopResultsTab(app)
            
        end

        % Cell edit callback: TopResultsTable
        function TopResultsTableCellEdit(app, event)
            % Select curve
            totalnbCurves = size(app.TopResultsTable.Data,1);
            indexSelectedCurve = totalnbCurves-event.Indices(1)+1;
            
            curves = findobj(app.CurveAxes_2,'LineStyle','-');
              
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            
            % Select curve
            totalnbCurves = size(app.UITable.Data,1);
            indexSelectedCurve = totalnbCurves-event.Indices(1) + 1;
            
            curves = findobj(app.CurveAxes,'LineStyle','-');
            set(curves, 'Selected','off')
            curves(indexSelectedCurve).Selected = 'on';
            
            % Select row in plate
            rectObj = findobj(app.Microplateplot.Children,'Type','Rectangle');
            delete(rectObj);
            
            posx=app.Microplateplot.XLim(1) + 0.5;
            posy=event.Indices(1) - 0.5;
            width = app.Microplateplot.XLim(2) - 1;
            height = 1;
            rectangle(app.Microplateplot,'Position',[posx posy width height],...
                'EdgeColor',[128 170 202]/255,'FaceColor',[211 238 255]/255);
            app.Microplateplot.Children = app.Microplateplot.Children(end:-1:1);
            
            % Activate 'clear' button
            app.ClearselectionButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MicroplateanalysisUIFigure = uifigure('Visible', 'off');
            app.MicroplateanalysisUIFigure.Name = 'Microplate analysis';
            app.TabGroup = uitabgroup(app.MicroplateanalysisUIFigure);
            app.PlateBrowserTab = uitab(app.TabGroup);
            app.PlateBrowserTab.Title = 'Plate Browser';
            app.Microplateplot = uiaxes(app.PlateBrowserTab);
            title(app.Microplateplot, 'Microplate Plot')
            app.Microplateplot.XTick = zeros(1,0);
            app.Microplateplot.XTickLabel = {};
            app.Microplateplot.YTick = zeros(1,0);
            app.CurveAxes = uiaxes(app.PlateBrowserTab);
            title(app.CurveAxes, 'EC50 Curves')
            xlabel(app.CurveAxes, 'Log [Compound]')
            ylabel(app.CurveAxes, '% Signal')
            app.NextFileButton = uibutton(app.PlateBrowserTab, 'push');
            app.NextFileButton.ButtonPushedFcn = createCallbackFcn(app, @NextFileButtonPushed, true);
            app.NextFileButton.Text = 'Next File';
            app.PreviousFileButton = uibutton(app.PlateBrowserTab, 'push');
            app.PreviousFileButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousFileButtonPushed, true);
            app.PreviousFileButton.Text = 'Previous File';
            app.ClearselectionButton = uibutton(app.PlateBrowserTab, 'push');
            app.ClearselectionButton.ButtonPushedFcn = createCallbackFcn(app, @ClearselectionButtonPushed, true);
            app.ClearselectionButton.Enable = 'off';
            app.ClearselectionButton.Text = 'Clear selection';
            app.UITable = uitable(app.PlateBrowserTab);
            app.UITable.ColumnName = {'File'; 'Compound Nr'; 'NegControl'; 'Conc1'; 'Conc2'; 'Conc3'; 'Conc4'; 'Conc5'; 'Conc6'; 'Conc7'; 'Conc8'; 'Conc9'; 'Conc10'; 'PosControl'; 'EC50'; 'Hills slope'; 'max'; 'min'; 'SSE'; 'Rsquare'; 'Lower bend'; 'Upper bend'; 'Reliable'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.FileNameField = uieditfield(app.PlateBrowserTab, 'text');
            app.FileNameField.Editable = 'off';
            app.CurrentFileLabel = uilabel(app.PlateBrowserTab);
            app.CurrentFileLabel.Text = 'Current File:';
            app.SelectFilesButton = uibutton(app.PlateBrowserTab, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.SummaryTableTab = uitab(app.TabGroup);
            app.SummaryTableTab.Title = 'Summary Tables';
            app.AnalyzeSelectedFilesButton = uibutton(app.SummaryTableTab, 'push');
            app.AnalyzeSelectedFilesButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeSelectedFilesButtonPushed, true);
            app.AnalyzeSelectedFilesButton.Text = 'Analyze Selected Files';
            app.TabGroup2 = uitabgroup(app.SummaryTableTab);
            app.TopResultsTab = uitab(app.TabGroup2);
            app.TopResultsTab.Title = 'Top Results';
            app.TopResultsTable = uitable(app.TopResultsTab);
            app.TopResultsTable.ColumnName = {'FileName'; 'CompoundNumber'; 'EC50'; 'Reliable'};
            app.TopResultsTable.RowName = {};
            app.TopResultsTable.CellEditCallback = createCallbackFcn(app, @TopResultsTableCellEdit, true);
            app.TopPercentiletoDisplayEditFieldLabel = uilabel(app.TopResultsTab);
            app.TopPercentiletoDisplayEditFieldLabel.Text = 'Top Percentile to Display:';
            app.TopPercentiletoDisplayEditField = uieditfield(app.TopResultsTab, 'numeric');
            app.TopPercentiletoDisplayEditField.Limits = [0 100];
            app.TopPercentiletoDisplayEditField.ValueChangedFcn = createCallbackFcn(app, @TopPercentiletoDisplayEditFieldValueChanged, true);
            app.TopPercentiletoDisplayEditField.Value = 100;
            app.CurveAxes_2 = uiaxes(app.TopResultsTab);
            title(app.CurveAxes_2, 'EC50 Curves')
            xlabel(app.CurveAxes_2, 'Log [Compound]')
            ylabel(app.CurveAxes_2, '% Signal')
            app.AllResultsTab = uitab(app.TabGroup2);
            app.AllResultsTab.Title = 'All Results';
            app.AllResultsTable = uitable(app.AllResultsTab);
            app.AllResultsTable.ColumnName = {'File'; 'Compound Nr'; 'NegControl'; 'Conc1'; 'Conc2'; 'Conc3'; 'Conc4'; 'Conc5'; 'Conc6'; 'Conc7'; 'Conc8'; 'Conc9'; 'Conc10'; 'PosControl'; 'EC50'; 'Hills slope'; 'max'; 'min'; 'SSE'; 'Rsquare'; 'Lower bend'; 'Upper bend'; 'Reliable'};
            app.AllResultsTable.RowName = {};
            app.SaveResultsButton = uibutton(app.SummaryTableTab, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            % Show the figure after all components are created
            app.MicroplateanalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MicroPlateBrowser_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MicroplateanalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MicroplateanalysisUIFigure)
        end
    end
end